classdef Tanque_Capacitancia_PROYECTO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        VolumenmLEditField           matlab.ui.control.NumericEditField
        VolumenmLEditFieldLabel      matlab.ui.control.Label
        RangosPosiblespF             matlab.ui.control.TextArea
        ElevacindelquidocmEditField  matlab.ui.control.NumericEditField
        ElevacindelquidocmEditFieldLabel  matlab.ui.control.Label
        Dielectrico                  matlab.ui.control.DropDown
        DielctricoLabel              matlab.ui.control.Label
        Capacitancia                 matlab.ui.control.Slider
        CapacitanciaLabel            matlab.ui.control.Label
        InicioButton                 matlab.ui.control.Button
        Ejes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
% Función que grafica un prisma, dada por ChatGPT
function drawCuboid(ax, origin, dims, color, alpha)
                % origin: [x0 y0 z0]
                % dims: [dx dy dz]
                x0 = origin(1); y0 = origin(2); z0 = origin(3);
                dx = dims(1); dy = dims(2); dz = dims(3);

                vertices = [
                 x0      y0      z0;
                 x0+dx   y0      z0;
                 x0+dx   y0+dy   z0;
                 x0      y0+dy   z0;
                 x0      y0      z0+dz;
                 x0+dx   y0      z0+dz;
                 x0+dx   y0+dy   z0+dz;
                 x0      y0+dy   z0+dz;
                 ];

                 faces = [
                 1 2 3 4;
                 5 6 7 8;
                 1 2 6 5;
                 2 3 7 6;
                 3 4 8 7;
                 4 1 5 8;
                 ];

    patch(ax, 'Vertices', vertices, 'Faces', faces, ...
        'FaceColor', color, 'EdgeColor', 'none', 'FaceAlpha', alpha);
end
            cla(app.Ejes); % Reinicio con cada toque de botón
            hold(app.Ejes,"on") % Hace que la grafica sea manipulables
            view(app.Ejes,45,45); % Vista inicial de la gráfica en 3D


            dielectrico=app.Dielectrico.Value; % Obtiene el dieléctrico
            capacitancia_elegida=app.Capacitancia.Value; % Obtiene la capacitancia 
            botella_width = 10; % Ancho de la botella
            botella_depth = 10; % Largo de la botella
            botella_height = 4; % Alto de la botella

    % Dibuja la botella (transparente)
    drawCuboid(app.Ejes, [-5, -5, 0], [botella_width, botella_depth, botella_height], 'blue', 0.1);

    % Obtiene la altura del líquido según las fórmulas, y le asigna un
    % color
    L2=0; 
    e0=8.85e-12;
    d=0.005;
    alpha=0.066;
    L=0.04; 
    % Caso uno, agua experimental
    if dielectrico == "Agua (Teórica)"
        color = 'blue'; 
        k=80; 
        if capacitancia_elegida<=373
        L2 = (1 / (k - 1)) * ( (capacitancia_elegida*1e-12*d) / (e0 * alpha) - L );
        else 
        return;
        end 
    elseif dielectrico == "Agua (Experimental)" 
        color = 'blue';
        if capacitancia_elegida>= 37 && capacitancia_elegida<=98
        L2 = ((6.4687*1e8)*(capacitancia_elegida*1e-12))- 0.023622;  
        else 
        return;
        end 
    elseif dielectrico == "Aceite de Silicón"
        color = 'white';
        k=2.5;
        if capacitancia_elegida<=11
        L2 = (1 / (k - 1)) * ( (capacitancia_elegida*1e-12*d) / (e0 * alpha) - L );
        else 
        return;
        end 
    elseif dielectrico == "Aceite de Oliva"
        k=3.1;
        color = 'yellow';
        if capacitancia_elegida<=14
        L2 = (1 / (k - 1)) * ( (capacitancia_elegida*1e-12*d) / (e0 * alpha) - L );
        else 
        return;
        end 
     elseif dielectrico == "Vinagre"
        k=24;
        color = 'red';
        if capacitancia_elegida<=112
        L2 = (1 / (k - 1)) * ( (capacitancia_elegida*1e-12*d) / (e0 * alpha) - L );
        else 
        return;
        end 
     elseif dielectrico == "Vino"
        k=25;
        color = 'magenta';
        if capacitancia_elegida<=116
        L2 = (1 / (k - 1)) * ( (capacitancia_elegida*1e-12*d) / (e0 * alpha) - L );
        else 
        return;
        end 
    end
    
    altura_liquido=L2*100;

    % El valor mínimo de capacitancia está dado por (e_o*alpha*L)/d, y es
    % aproximadamente 4.6pF

    % Dibuja el líquido (opaco)
    drawCuboid(app.Ejes, [-5, -5, 0], [botella_width, botella_depth, altura_liquido], color, 1);

    z_vals = linspace(0, 4, 2); % altura segmentada
    x_vals = linspace(-3.3, 3.3, 2);
    [Xp, Zp] = meshgrid(x_vals, z_vals);

    Yfront = (0.25) * ones(size(Xp));   % Frente
    Yback  = (-0.25) * ones(size(Xp));  % Atrás

    % Panel al frente
    surf(app.Ejes, Xp, Yfront, Zp, 'FaceColor', 'black', 'EdgeColor', 'none', 'FaceAlpha', 1);

    % Panel atrás
    surf(app.Ejes, Xp, Yback, Zp, 'FaceColor', 'black' , 'EdgeColor', 'none', 'FaceAlpha', 1);

    % Superficie del líquido (como tapa rectangular)
    fill3(app.Ejes, ...
    [-5 5 5 -5], ...     % X corners
    [-5 -5 5 5], ...     % Y corners
    altura_liquido * ones(1,4), ... % Z flat
    color);

    app.VolumenmLEditField.Value = 100*altura_liquido;
    app.ElevacindelquidocmEditField.Value = altura_liquido;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Ejes = uiaxes(app.UIFigure);
            title(app.Ejes, 'Title')
            xlabel(app.Ejes, 'X')
            ylabel(app.Ejes, 'Y')
            zlabel(app.Ejes, 'Z')
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Icon = fullfile(pathToMLAPP, 'start.png');
            app.InicioButton.Text = 'Inicio';
            app.CapacitanciaLabel = uilabel(app.UIFigure);
            app.CapacitanciaLabel.Text = 'Capacitancia (pF)';
            app.Capacitancia = uislider(app.UIFigure);
            app.Capacitancia.Limits = [5 373];
            app.Capacitancia.Value = 5;
            app.DielctricoLabel = uilabel(app.UIFigure);
            app.DielctricoLabel.Text = 'Dieléctrico';
            app.Dielectrico = uidropdown(app.UIFigure);
            app.Dielectrico.Items = {'Agua (Experimental)', 'Agua (Teórica)', 'Aceite de Oliva', 'Aceite de Silicón', 'Vinagre', 'Vino'};
            app.Dielectrico.Value = 'Agua (Experimental)';
            app.ElevacindelquidocmEditFieldLabel = uilabel(app.UIFigure);
            app.ElevacindelquidocmEditFieldLabel.Text = 'Elevación de líquido (cm)';
            app.ElevacindelquidocmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangosPosiblespF = uitextarea(app.UIFigure);
            app.RangosPosiblespF.Value = {'LÍMITES DE CAPACITANCIA'; 'Agua (Experimental) [37, 98 pF]'; 'Agua (Teórica) 373pF'; 'Aceite de Oliva 14pF'; 'Aceite de Silicón 11pF '; 'Vinagre, 112pF'; 'Vino, 116pF'; ''};
            app.VolumenmLEditFieldLabel = uilabel(app.UIFigure);
            app.VolumenmLEditFieldLabel.Text = 'Volumen (mL)';
            app.VolumenmLEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'itesm.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tanque_Capacitancia_PROYECTO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end